classdef PRAC_1_Voz_Equipo2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        FFTCentradaLabel           matlab.ui.control.Label
        FFTLabel                   matlab.ui.control.Label
        FaseLabel                  matlab.ui.control.Label
        SealesLabel_2              matlab.ui.control.Label
        AccionesLabel              matlab.ui.control.Label
        FuncionesLabel             matlab.ui.control.Label
        CBsumaSenales              matlab.ui.control.CheckBox
        CBtriangular               matlab.ui.control.CheckBox
        CBcuadrada                 matlab.ui.control.CheckBox
        CBruidoSenales             matlab.ui.control.CheckBox
        CBcoseno                   matlab.ui.control.CheckBox
        CBseno                     matlab.ui.control.CheckBox
        ActualizarDatosButton      matlab.ui.control.Button
        milisegundosLabel          matlab.ui.control.Label
        tiempo                     matlab.ui.control.NumericEditField
        TiempoLabel                matlab.ui.control.Label
        HzLabel_2                  matlab.ui.control.Label
        fmuestreo                  matlab.ui.control.NumericEditField
        FrecuenciademuestreoLabel  matlab.ui.control.Label
        gradosLabel                matlab.ui.control.Label
        desfase                    matlab.ui.control.NumericEditField
        DesfaseLabel               matlab.ui.control.Label
        HzLabel                    matlab.ui.control.Label
        frecuencia                 matlab.ui.control.NumericEditField
        FrecuenciaLabel            matlab.ui.control.Label
        amplitud                   matlab.ui.control.Spinner
        AmplitudLabel              matlab.ui.control.Label
        SealesLabel                matlab.ui.control.Label
        axeFase                    matlab.ui.control.UIAxes
        axeFFT                     matlab.ui.control.UIAxes
        axeFFTcentrada             matlab.ui.control.UIAxes
        axeNormal                  matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: amplitud
        function amplitudValueChanged(app, event)


        end

        % Callback function: ActualizarDatosButton, CBcoseno, CBcuadrada, 
        % ...and 4 other components
        function GraficarButtonPushed(app, event)
            global A f des fs tiempo;
            cla(app.axeNormal, "reset")
            cla(app.axeFFTcentrada, "reset") 
            cla(app.axeFFT, "reset") 
            cla(app.axeFase, "reset") 

            A = app.amplitud.Value; % Amplitud de las señales
            f = app.frecuencia.Value; % Frecuencia de las señales (Hz)
            des = app.desfase.Value; % Desfase de la señal 
            fs = app.fmuestreo.Value; % Frecuencia de muestreo (Hz)
            tiempo = app.tiempo.Value; % Tiempo total de grabación (milisegundos)
            
            % Tiempo continuo y muestreo
            T = tiempo / 1000; % Convertir tiempo a segundos
            Ts = 1 / fs; % Periodo de muestreo
            t = 0:Ts:T-Ts; % Vector de tiempo discreto (muestreo)
            
            N = length(t);
            % Variables para las señales
            x_sin = zeros(size(t));
            x_cos = zeros(size(t));
            x_square = zeros(size(t));
            x_sawtooth = zeros(size(t));
            x_sum = zeros(size(t));

            freqs = (0:N-1)*(fs/N); % Frecuencias FFT
            fft_x_sin = zeros(size(freqs));
            fft_x_cos = zeros(size(freqs));
            fft_x_square = zeros(size(freqs));
            fft_x_sawtooth = zeros(size(freqs));
            fft_x_sum = zeros(size(freqs));

            freqs_central = (-N/2:N/2-1)*(fs/N); % Frecuencias Centrada
            fft_centrada_x_sin = zeros(size(freqs_central));
            fft_centrada_x_cos= zeros(size(freqs_central));
            fft_centrada_x_square = zeros(size(freqs_central));
            fft_centrada_x_sawtooth= zeros(size(freqs_central));
            x_sum_freqs_central = zeros(size(freqs_central));

            fase_x_sin = zeros(size(freqs));
            fase_x_cos = zeros(size(freqs));
            fase_x_sw = zeros(size(freqs));
            fase_x_sawtooth = zeros(size(freqs));
            x_sum_fase = zeros(size(freqs));

            % Señal seno
            if app.CBseno.Value == 1
                x_sin = A * sin(2 * pi * f * t + deg2rad(des));
           % Aplicar ruido gaussiano si la casilla está seleccionada
                if app.CBruidoSenales.Value == 1
                    ruidoGauss = randn(size(x_sin)); % Generar ruido gaussiano
                    x_sin = x_sin + ruidoGauss; % Añadir ruido a la señal seno
                end

                % FFT de la señal 
                fft_x_sin = fft(x_sin);
                fft_x_sum= fft_x_sum+fft_x_sin;
                plot(app.axeFFT, freqs, abs(fft_x_sin) / length(t), 'b', 'LineWidth', 1.5); % Graficar señal seno
                hold(app.axeFFT, 'on');

                % FFT de la señal centrada
                fft_centrada_x_sin = fftshift(fft_x_sin); 
                x_sum_freqs_central= x_sum_freqs_central+fft_centrada_x_sin;
                plot(app.axeFFTcentrada, freqs_central, abs(fft_centrada_x_sin) / length(t), 'b', 'LineWidth', 1.5); % Graficar señal seno
                hold(app.axeFFTcentrada, 'on');

                % fase
                fase_x_sin = angle(fft_centrada_x_sin);
                x_sum_fase= x_sum_fase + fase_x_sin;
                plot(app.axeFase, freqs_central, fase_x_sin, 'b', 'LineWidth', 1.5); % Graficar señal seno
                hold(app.axeFase, 'on');

                x_sum = x_sum + x_sin;
                plot(app.axeNormal, t, x_sin, 'b', 'LineWidth', 1.5); % Graficar señal seno
                hold(app.axeNormal, 'on');
            end
            
            % Señal coseno
            if app.CBcoseno.Value == 1
                x_cos = A * cos(2 * pi * f * t + deg2rad(des));
                
                % Aplicar ruido gaussiano si la casilla está seleccionada
                if app.CBruidoSenales.Value == 1
                    ruidoGauss = randn(size(x_cos)); % Generar ruido gaussiano
                    x_cos = x_cos + ruidoGauss; % Añadir ruido a la señal coseno
                end
                
                % FFT de la señal 
                fft_x_cos = fft(x_cos);
                fft_x_sum= fft_x_sum+fft_x_cos;
                plot(app.axeFFT, freqs, abs(fft_x_cos) / length(t), 'r', 'LineWidth', 1.5); % Graficar señal seno
                hold(app.axeFFT, 'on');


                fft_centrada_x_cos = fftshift(fft_x_cos); % FFT de la señal
                x_sum_freqs_central= x_sum_freqs_central+fft_centrada_x_cos;
                plot(app.axeFFTcentrada, freqs_central, abs(fft_centrada_x_cos) / length(t), 'r', 'LineWidth', 1.5); % Graficar señal seno
                hold(app.axeFFTcentrada, 'on');
                
                % fase
                fase_x_cos = angle(fft_centrada_x_cos);
                x_sum_fase= x_sum_fase + fase_x_cos;
                plot(app.axeFase, freqs_central, fase_x_cos, 'r', 'LineWidth', 1.5); % Graficar señal seno
                hold(app.axeFase, 'on');


                x_sum = x_sum + x_cos;
                plot(app.axeNormal, t, x_cos, 'r', 'LineWidth', 1.5); % Graficar señal coseno
                hold(app.axeNormal, 'on');
            end
            
            % Señal Cuadrada
            if app.CBcuadrada.Value == 1
                x_square = A * square(2 * pi * f * t + deg2rad(des));
                
                % Aplicar ruido gaussiano si la casilla está seleccionada
                if app.CBruidoSenales.Value == 1
                    ruidoGauss = randn(size(x_square)); % Generar ruido gaussiano
                    x_square = x_square + ruidoGauss; % Añadir ruido a la señal cuadrada
                end

                % FFT de la señal 
                fft_x_square = fft(x_square);
                fft_x_sum= fft_x_sum+fft_x_square;
                plot(app.axeFFT, freqs, abs(fft_x_square) / length(t), 'g', 'LineWidth', 1.5); % Graficar señal seno
                hold(app.axeFFT, 'on');

                % FFT centrada
                fft_centrada_x_square = fftshift(fft_x_square); % FFT de la señal
                x_sum_freqs_central= x_sum_freqs_central+fft_centrada_x_square;
                plot(app.axeFFTcentrada, freqs_central, abs(fft_centrada_x_square) / length(t), 'g', 'LineWidth', 1.5); % Graficar señal seno
                hold(app.axeFFTcentrada, 'on');

                % fase
                fase_x_sw = angle(fft_centrada_x_square);
                x_sum_fase= x_sum_fase + fase_x_sw;
                plot(app.axeFase, freqs_central, fase_x_sw, 'g', 'LineWidth', 1.5); % Graficar señal seno
                hold(app.axeFase, 'on');
                
                x_sum = x_sum + x_square;
                plot(app.axeNormal, t, x_square, 'g', 'LineWidth', 1.5); % Graficar señal cuadrada
                hold(app.axeNormal, 'on');
            end
            
            % Señal Triangular
            if app.CBtriangular.Value == 1
                x_sawtooth = A * sawtooth(2 * pi * f * t + deg2rad(des));

                % Aplicar ruido gaussiano si la casilla está seleccionada
                if app.CBruidoSenales.Value == 1
                    ruidoGauss = randn(size(x_sawtooth)); % Generar ruido gaussiano
                    x_sawtooth = x_sawtooth + ruidoGauss; % Añadir ruido a la señal triangular
                end

                % FFT de la señal 
                fft_x_sawtooth = fft(x_sawtooth);
                fft_x_sum= fft_x_sum+fft_x_sawtooth;
                plot(app.axeFFT, freqs, abs(fft_x_sawtooth) / length(t), 'm', 'LineWidth', 1.5); % Graficar señal seno
                hold(app.axeFFT, 'on');

                % FFT centrada
                fft_centrada_x_sawtooth  = fftshift(fft_x_sawtooth); % FFT de la señal
                x_sum_freqs_central= x_sum_freqs_central+fft_centrada_x_sawtooth;

                plot(app.axeFFTcentrada, freqs_central, abs(fft_centrada_x_sawtooth) / length(t), 'm', 'LineWidth', 1.5); % Graficar señal seno
                hold(app.axeFFTcentrada, 'on');

                % fase
                fase_x_sawtooth = angle(fft_centrada_x_sawtooth);
                x_sum_fase= x_sum_fase + fase_x_sawtooth;
                plot(app.axeFase, freqs_central, fase_x_sawtooth, 'm', 'LineWidth', 1.5); % Graficar señal seno
                hold(app.axeFase, 'on');
                
                x_sum = x_sum + x_sawtooth;
                plot(app.axeNormal, t, x_sawtooth, 'm', 'LineWidth', 1.5); % Graficar señal triangular
                hold(app.axeNormal, 'on');
            end
            
            % Graficar la suma de las señales si el checkbox está seleccionado
            if app.CBsumaSenales.Value == 1
                % Aplicar ruido gaussiano a la suma de señales si la casilla está seleccionada
                if app.CBruidoSenales.Value == 1
                    ruidoGauss = randn(size(x_sum)); % Generar ruido gaussiano
                    x_sum = x_sum + ruidoGauss; % Añadir ruido a la suma de señales
                end

                plot(app.axeFase, freqs_central, x_sum_fase, 'k', 'LineWidth', 1.5); % Graficar señal seno
                hold(app.axeFase, 'on');

                plot(app.axeFFT, freqs, abs(fft_x_sum) / length(t), 'k', 'LineWidth', 1.5); % Graficar señal seno
                hold(app.axeFFT, 'on');

                plot(app.axeFFTcentrada, freqs_central, abs(x_sum_freqs_central) / length(t), 'k', 'LineWidth', 1.5); % Graficar señal seno
                hold(app.axeFFTcentrada, 'on');

                plot(app.axeNormal, t, x_sum, 'k', 'LineWidth', 1.5); % Graficar la suma de las señales en negro
                hold(app.axeNormal, 'on');
            end
            

            % Configuración del gráfico
            xlabel(app.axeNormal, 'Tiempo (s)');
            ylabel(app.axeNormal, 'Amplitud');
            
            % Crear una leyenda dinámica con las señales seleccionadas
            legendList = {};
            if app.CBseno.Value == 1
                legendList{end+1} = 'Señal Seno';
            end
            if app.CBcoseno.Value == 1
                legendList{end+1} = 'Señal Coseno';
            end
            if app.CBcuadrada.Value == 1
                legendList{end+1} = 'Señal Cuadrada';
            end
            if app.CBtriangular.Value == 1
                legendList{end+1} = 'Señal Triangular';
            end
            if app.CBsumaSenales.Value == 1
                legendList{end+1} = 'Suma de Señales';
            end
            legend(app.axeNormal, legendList);
            
            % Mostrar grilla
            grid(app.axeNormal, 'off');
            
            % Desactivar 'hold' al finalizar
            hold(app.axeNormal, 'off');
            hold(app.axeFFTcentrada, 'off');


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.axeNormal = uiaxes(app.UIFigure);
            xlabel(app.axeNormal, 'X')
            ylabel(app.axeNormal, 'Y')
            zlabel(app.axeNormal, 'Z')
            app.axeFFTcentrada = uiaxes(app.UIFigure);
            xlabel(app.axeFFTcentrada, 'X')
            ylabel(app.axeFFTcentrada, 'Y')
            zlabel(app.axeFFTcentrada, 'Z')
            app.axeFFT = uiaxes(app.UIFigure);
            xlabel(app.axeFFT, 'X')
            ylabel(app.axeFFT, 'Y')
            zlabel(app.axeFFT, 'Z')
            app.axeFase = uiaxes(app.UIFigure);
            xlabel(app.axeFase, 'X')
            ylabel(app.axeFase, 'Y')
            zlabel(app.axeFase, 'Z')
            app.SealesLabel = uilabel(app.UIFigure);
            app.SealesLabel.Text = 'Señales';
            app.AmplitudLabel = uilabel(app.UIFigure);
            app.AmplitudLabel.Text = 'Amplitud';
            app.amplitud = uispinner(app.UIFigure);
            app.amplitud.Step = 0.5;
            app.amplitud.Limits = [-10 10];
            app.amplitud.ValueChangedFcn = createCallbackFcn(app, @amplitudValueChanged, true);
            app.amplitud.Value = 2;
            app.FrecuenciaLabel = uilabel(app.UIFigure);
            app.FrecuenciaLabel.Text = 'Frecuencia';
            app.frecuencia = uieditfield(app.UIFigure, 'numeric');
            app.frecuencia.Value = 125;
            app.HzLabel = uilabel(app.UIFigure);
            app.HzLabel.Text = 'Hz';
            app.DesfaseLabel = uilabel(app.UIFigure);
            app.DesfaseLabel.Text = 'Desfase';
            app.desfase = uieditfield(app.UIFigure, 'numeric');
            app.desfase.Limits = [0 360];
            app.desfase.Value = 45;
            app.gradosLabel = uilabel(app.UIFigure);
            app.gradosLabel.Text = 'grados';
            app.FrecuenciademuestreoLabel = uilabel(app.UIFigure);
            app.FrecuenciademuestreoLabel.Text = {'Frecuencia '; 'de muestreo'};
            app.fmuestreo = uieditfield(app.UIFigure, 'numeric');
            app.fmuestreo.Value = 1000;
            app.HzLabel_2 = uilabel(app.UIFigure);
            app.HzLabel_2.Text = 'Hz';
            app.TiempoLabel = uilabel(app.UIFigure);
            app.TiempoLabel.Text = 'Tiempo';
            app.tiempo = uieditfield(app.UIFigure, 'numeric');
            app.tiempo.Limits = [0 Inf];
            app.tiempo.Value = 10;
            app.milisegundosLabel = uilabel(app.UIFigure);
            app.milisegundosLabel.Text = 'milisegundos';
            app.ActualizarDatosButton = uibutton(app.UIFigure, 'push');
            app.ActualizarDatosButton.ButtonPushedFcn = createCallbackFcn(app, @GraficarButtonPushed, true);
            app.ActualizarDatosButton.Text = 'Actualizar Datos';
            app.CBseno = uicheckbox(app.UIFigure);
            app.CBseno.ValueChangedFcn = createCallbackFcn(app, @GraficarButtonPushed, true);
            app.CBseno.Text = 'Seno';
            app.CBseno.Value = true;
            app.CBcoseno = uicheckbox(app.UIFigure);
            app.CBcoseno.ValueChangedFcn = createCallbackFcn(app, @GraficarButtonPushed, true);
            app.CBcoseno.Text = 'Coseno';
            app.CBruidoSenales = uicheckbox(app.UIFigure);
            app.CBruidoSenales.ValueChangedFcn = createCallbackFcn(app, @GraficarButtonPushed, true);
            app.CBruidoSenales.Text = 'Rudio';
            app.CBcuadrada = uicheckbox(app.UIFigure);
            app.CBcuadrada.ValueChangedFcn = createCallbackFcn(app, @GraficarButtonPushed, true);
            app.CBcuadrada.Text = 'Cuadrada';
            app.CBtriangular = uicheckbox(app.UIFigure);
            app.CBtriangular.ValueChangedFcn = createCallbackFcn(app, @GraficarButtonPushed, true);
            app.CBtriangular.Text = 'Triangular';
            app.CBsumaSenales = uicheckbox(app.UIFigure);
            app.CBsumaSenales.ValueChangedFcn = createCallbackFcn(app, @GraficarButtonPushed, true);
            app.CBsumaSenales.Text = 'Suma de señales';
            app.FuncionesLabel = uilabel(app.UIFigure);
            app.FuncionesLabel.Text = 'Funciones';
            app.AccionesLabel = uilabel(app.UIFigure);
            app.AccionesLabel.Text = 'Acciones';
            app.SealesLabel_2 = uilabel(app.UIFigure);
            app.SealesLabel_2.Text = 'Señales';
            app.FaseLabel = uilabel(app.UIFigure);
            app.FaseLabel.Text = 'Fase';
            app.FFTLabel = uilabel(app.UIFigure);
            app.FFTLabel.Text = 'FFT';
            app.FFTCentradaLabel = uilabel(app.UIFigure);
            app.FFTCentradaLabel.Text = 'FFT Centrada';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PRAC_1_Voz_Equipo2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end