classdef P4interfaz < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        EditField                       matlab.ui.control.NumericEditField
        EditFieldLabel                  matlab.ui.control.Label
        CBruido                         matlab.ui.control.CheckBox
        frecuenciafundamentalFFTconruidoLabel  matlab.ui.control.Label
        RuidoFF                         matlab.ui.control.EditField
        Audio3Music                     matlab.ui.control.Button
        Audio2Music                     matlab.ui.control.Button
        Audio1Music                     matlab.ui.control.Button
        MusicaLabel                     matlab.ui.control.Label
        Audio3Childrens                 matlab.ui.control.Button
        Audio2Childrens                 matlab.ui.control.Button
        Audio1Childrens                 matlab.ui.control.Button
        NiosjugandoLabel                matlab.ui.control.Label
        frecuenciafundamentalFFTLabel   matlab.ui.control.Label
        frecuenciaFundamental           matlab.ui.control.EditField
        Audio3Construccion              matlab.ui.control.Button
        Audio2Construccion              matlab.ui.control.Button
        Audio1Construccion              matlab.ui.control.Button
        ConstruccinLabel                matlab.ui.control.Label
        Audio3Perros                    matlab.ui.control.Button
        Audio2Perros                    matlab.ui.control.Button
        Audio1Perros                    matlab.ui.control.Button
        PerrosLabel                     matlab.ui.control.Label
        ExtraccindecaractersticasLabel  matlab.ui.control.Label
        RudioFFT                        matlab.ui.control.UIAxes
        RudioAxis                       matlab.ui.control.UIAxes
        axisFFT                         matlab.ui.control.UIAxes
        senal                           matlab.ui.control.UIAxes
    end

methods (Access = public)  
  function calcularFrecuenciaFundamental(app, audio, Fs)
        % Limpia los ejes cada vez que se llama a la función
        cla(app.senal, "reset");
        cla(app.axisFFT, "reset");
        cla(app.RudioAxis, "reset");
        cla(app.RudioFFT, "reset");

        % Reproduce el audio
        sound(audio, Fs);
        
        % Parámetros
        N = length(audio);  % Número de muestras
        t = (0:N-1)/Fs;     % Vector de tiempo en segundos
        
        % Graficar la señal en el dominio del tiempo
        plot(app.senal, t, audio, "k", 'LineWidth', 1.5);
        title(app.senal, 'Señal de Audio en el Tiempo');
        xlabel(app.senal, 'Tiempo [s]');
        ylabel(app.senal, 'Amplitud');
        
        % Transformada de Fourier (FFT)
        Y = fft(audio);                     
        P2 = abs(Y/N);                      
        P1 = P2(1:floor(N/2)+1);            
        P1(2:end-1) = 2*P1(2:end-1);        
        f = Fs*(0:floor(N/2))/N;            
        
        % Graficar el espectro de amplitud de un solo lado
        plot(app.axisFFT, f, P1, "k", 'LineWidth', 1.5);
        title(app.axisFFT, 'Espectro de Amplitud de la Señal de Audio');
        xlabel(app.axisFFT, 'Frecuencia [Hz]');
        ylabel(app.axisFFT, '|P1(f)|');
        
        % Encontrar la frecuencia fundamental
        [magnitud_max, idx] = max(P1);        
        frecuencia_fundamental = f(idx);      
        app.frecuenciaFundamental.Value = num2str(frecuencia_fundamental);

        % Comprueba el estado del checkbox para añadir ruido si es necesario
        if app.CBruido.Value  
            % Añadir ruido a la señal original
            X = audio + randn(size(audio));
            
            % Graficar señal ruidosa en el dominio del tiempo
            plot(app.RudioAxis, t, X, "k", 'LineWidth', 1.5);
            title(app.RudioAxis, 'Señal de Audio en el Tiempo con Ruido');
            xlabel(app.RudioAxis, 'Tiempo [s]');
            ylabel(app.RudioAxis, 'Amplitud');
            
            % Transformada de Fourier de la señal ruidosa
            Y_noise = fft(X);                     
            P2_noise = abs(Y_noise/N);            
            P1_noise = P2_noise(1:floor(N/2)+1);  
            P1_noise(2:end-1) = 2*P1_noise(2:end-1);
    
            % Encontrar la frecuencia fundamental
            [magnitud_max_noise, idx_noise] = max(P1_noise);        
            frecuencia_fundamental_ruido = f(idx_noise);     
            app.RuidoFF.Value = num2str(frecuencia_fundamental_ruido);
        
            % Graficar el espectro de amplitud de la señal ruidosa
            plot(app.RudioFFT, f, P1_noise, "k", 'LineWidth', 1.5);
            title(app.RudioFFT, 'Espectro de Amplitud de la Señal con Ruido');
            xlabel(app.RudioFFT, 'Frecuencia [Hz]');
            ylabel(app.RudioFFT, '|P1\_noise(f)|');
        end
    end
end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.CBruido.Value = true; % Define un valor inicial para el checkbox

            cla(app.senal, "reset")
            cla(app.axisFFT, "reset")
            cla(app.RudioAxis, "reset")
            cla(app.RudioFFT, "reset")
        end

        % Button pushed function: Audio1Perros
        function Audio1PerrosButtonPushed(app, event)

           [audio, Fs] = audioread("perros1.wav");
           app.calcularFrecuenciaFundamental(audio, Fs)

        end

        % Button pushed function: Audio2Perros
        function Audio2PerrosButtonPushed(app, event)
           [audio, Fs] = audioread("perros2.wav");
           app.calcularFrecuenciaFundamental(audio, Fs)
        end

        % Button pushed function: Audio3Perros
        function Audio3PerrosButtonPushed(app, event)
           [audio, Fs] = audioread("perros3.wav");
           app.calcularFrecuenciaFundamental(audio, Fs)
        end

        % Button pushed function: Audio1Construccion
        function Audio1ConstruccionButtonPushed(app, event)
           [audio, Fs] = audioread("construccion1.wav");
           app.calcularFrecuenciaFundamental(audio, Fs) 
        end

        % Button pushed function: Audio2Construccion
        function Audio2ConstruccionButtonPushed(app, event)
           [audio, Fs] = audioread("construccion2.wav");
           app.calcularFrecuenciaFundamental(audio, Fs)      
        end

        % Button pushed function: Audio3Construccion
        function Audio3ConstruccionButtonPushed(app, event)
           [audio, Fs] = audioread("construccion3.wav");
           app.calcularFrecuenciaFundamental(audio, Fs)    
        end

        % Button pushed function: Audio1Childrens
        function Audio1ChildrensButtonPushed(app, event)
           [audio, Fs] = audioread("childrens1.wav");
           app.calcularFrecuenciaFundamental(audio, Fs)    
        end

        % Button pushed function: Audio2Childrens
        function Audio2ChildrensButtonPushed(app, event)
           [audio, Fs] = audioread("childrens2.wav");
           app.calcularFrecuenciaFundamental(audio, Fs)    
        end

        % Button pushed function: Audio3Childrens
        function Audio3ChildrensButtonPushed(app, event)
           [audio, Fs] = audioread("childrens3.wav");
           app.calcularFrecuenciaFundamental(audio, Fs)    
        end

        % Button pushed function: Audio1Music
        function Audio1MusicButtonPushed(app, event)
           [audio, Fs] = audioread("music1.wav");
           app.calcularFrecuenciaFundamental(audio, Fs)    
        end

        % Button pushed function: Audio2Music
        function Audio2MusicButtonPushed(app, event)
           [audio, Fs] = audioread("music2.wav");
           app.calcularFrecuenciaFundamental(audio, Fs)    
        end

        % Button pushed function: Audio3Music
        function Audio3MusicButtonPushed(app, event)
           [audio, Fs] = audioread("music3.wav");
           app.calcularFrecuenciaFundamental(audio, Fs)    
        end

        % Callback function: not associated with a component
        function CBruidoValueChanged(app, event)
            value = app.CBruido.Value;
            
        end

        % Value changed function: CBruido
        function CBruidoValueChanged2(app, event)
            value = app.CBruido.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.senal = uiaxes(app.UIFigure);
            title(app.senal, 'Señal de Audio')
            xlabel(app.senal, 'Tiempo [s]')
            ylabel(app.senal, 'Amplitud')
            zlabel(app.senal, 'Z')
            app.axisFFT = uiaxes(app.UIFigure);
            title(app.axisFFT, 'Transformada de Fourier')
            xlabel(app.axisFFT, 'Frecuencia [Hz]')
            ylabel(app.axisFFT, 'Magnitud')
            zlabel(app.axisFFT, 'Z')
            app.RudioAxis = uiaxes(app.UIFigure);
            title(app.RudioAxis, 'Señal de Audio')
            xlabel(app.RudioAxis, 'Tiempo [s]')
            ylabel(app.RudioAxis, 'Amplitud')
            zlabel(app.RudioAxis, 'Z')
            app.RudioFFT = uiaxes(app.UIFigure);
            title(app.RudioFFT, 'Transformada de Fourier')
            xlabel(app.RudioFFT, 'Frecuencia [Hz]')
            ylabel(app.RudioFFT, 'Magnitud')
            zlabel(app.RudioFFT, 'Z')
            app.ExtraccindecaractersticasLabel = uilabel(app.UIFigure);
            app.ExtraccindecaractersticasLabel.Text = 'Extracción de características';
            app.PerrosLabel = uilabel(app.UIFigure);
            app.PerrosLabel.Text = 'Perros';
            app.Audio1Perros = uibutton(app.UIFigure, 'push');
            app.Audio1Perros.ButtonPushedFcn = createCallbackFcn(app, @Audio1PerrosButtonPushed, true);
            app.Audio1Perros.Text = 'Audio 1';
            app.Audio2Perros = uibutton(app.UIFigure, 'push');
            app.Audio2Perros.ButtonPushedFcn = createCallbackFcn(app, @Audio2PerrosButtonPushed, true);
            app.Audio2Perros.Text = 'Audio 2';
            app.Audio3Perros = uibutton(app.UIFigure, 'push');
            app.Audio3Perros.ButtonPushedFcn = createCallbackFcn(app, @Audio3PerrosButtonPushed, true);
            app.Audio3Perros.Text = 'Audio 3';
            app.ConstruccinLabel = uilabel(app.UIFigure);
            app.ConstruccinLabel.Text = 'Construcción';
            app.Audio1Construccion = uibutton(app.UIFigure, 'push');
            app.Audio1Construccion.ButtonPushedFcn = createCallbackFcn(app, @Audio1ConstruccionButtonPushed, true);
            app.Audio1Construccion.Text = 'Audio 1';
            app.Audio2Construccion = uibutton(app.UIFigure, 'push');
            app.Audio2Construccion.ButtonPushedFcn = createCallbackFcn(app, @Audio2ConstruccionButtonPushed, true);
            app.Audio2Construccion.Text = 'Audio 2';
            app.Audio3Construccion = uibutton(app.UIFigure, 'push');
            app.Audio3Construccion.ButtonPushedFcn = createCallbackFcn(app, @Audio3ConstruccionButtonPushed, true);
            app.Audio3Construccion.Text = 'Audio 3';
            app.frecuenciaFundamental = uieditfield(app.UIFigure, 'text');
            app.frecuenciaFundamental.Editable = 'off';
            app.frecuenciafundamentalFFTLabel = uilabel(app.UIFigure);
            app.frecuenciafundamentalFFTLabel.Text = 'frecuencia fundamental FFT';
            app.NiosjugandoLabel = uilabel(app.UIFigure);
            app.NiosjugandoLabel.Text = 'Niños jugando';
            app.Audio1Childrens = uibutton(app.UIFigure, 'push');
            app.Audio1Childrens.ButtonPushedFcn = createCallbackFcn(app, @Audio1ChildrensButtonPushed, true);
            app.Audio1Childrens.Text = 'Audio 1';
            app.Audio2Childrens = uibutton(app.UIFigure, 'push');
            app.Audio2Childrens.ButtonPushedFcn = createCallbackFcn(app, @Audio2ChildrensButtonPushed, true);
            app.Audio2Childrens.Text = 'Audio 2';
            app.Audio3Childrens = uibutton(app.UIFigure, 'push');
            app.Audio3Childrens.ButtonPushedFcn = createCallbackFcn(app, @Audio3ChildrensButtonPushed, true);
            app.Audio3Childrens.Text = 'Audio 3';
            app.MusicaLabel = uilabel(app.UIFigure);
            app.MusicaLabel.Text = 'Musica';
            app.Audio1Music = uibutton(app.UIFigure, 'push');
            app.Audio1Music.ButtonPushedFcn = createCallbackFcn(app, @Audio1MusicButtonPushed, true);
            app.Audio1Music.Text = 'Audio 1';
            app.Audio2Music = uibutton(app.UIFigure, 'push');
            app.Audio2Music.ButtonPushedFcn = createCallbackFcn(app, @Audio2MusicButtonPushed, true);
            app.Audio2Music.Text = 'Audio 2';
            app.Audio3Music = uibutton(app.UIFigure, 'push');
            app.Audio3Music.ButtonPushedFcn = createCallbackFcn(app, @Audio3MusicButtonPushed, true);
            app.Audio3Music.Text = 'Audio 3';
            app.RuidoFF = uieditfield(app.UIFigure, 'text');
            app.RuidoFF.Editable = 'off';
            app.frecuenciafundamentalFFTconruidoLabel = uilabel(app.UIFigure);
            app.frecuenciafundamentalFFTconruidoLabel.Text = 'frecuencia fundamental FFT con ruido';
            app.CBruido = uicheckbox(app.UIFigure);
            app.CBruido.ValueChangedFcn = createCallbackFcn(app, @CBruidoValueChanged2, true);
            app.CBruido.Text = 'Rudio';
            app.CBruido.Value = true;
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = P4interfaz

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end