classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Audio3Music                     matlab.ui.control.Button
        Audio2Music                     matlab.ui.control.Button
        Audio1Music                     matlab.ui.control.Button
        MusicaLabel                     matlab.ui.control.Label
        Audio3Childrens                 matlab.ui.control.Button
        Audio2Childrens                 matlab.ui.control.Button
        Audio1Childrens                 matlab.ui.control.Button
        NiosjugandoLabel                matlab.ui.control.Label
        frecuenciafundamentalLabel      matlab.ui.control.Label
        frecuenciaFundamental           matlab.ui.control.EditField
        Audio3Construccion              matlab.ui.control.Button
        Audio2Construccion              matlab.ui.control.Button
        Audio1Construccion              matlab.ui.control.Button
        ConstruccinLabel                matlab.ui.control.Label
        Audio3Perros                    matlab.ui.control.Button
        Audio2Perros                    matlab.ui.control.Button
        Audio1Perros                    matlab.ui.control.Button
        PerrosLabel                     matlab.ui.control.Label
        ExtraccindecaractersticasLabel  matlab.ui.control.Label
        axisFFT                         matlab.ui.control.UIAxes
        senal                           matlab.ui.control.UIAxes
    end

methods (Access = public)  
    function calcularFrecuenciaFundamental(app, audio, Fs)
        % Esta función calcula la frecuencia fundamental de una señal de audio
        % Entrada: 
        %   audio - señal de audio
        %   Fs - frecuencia de muestreo
        % Salida:
        %   frecuencia_fundamental - la frecuencia fundamental calculada

        sound(audio, Fs);
        
        N = length(audio);  % Número de muestras
        t = (0:N-1)/Fs;  % Vector de tiempo para graficar la señal en segundos
    
        % Graficar señal en el dominio del tiempo
        plot(app.senal, t, audio, "k", 'LineWidth', 1.5);
        title(app.senal, 'Señal de Audio en el Tiempo');
        xlabel(app.senal, 'Tiempo [s]');
        ylabel(app.senal, 'Amplitud');

        % FFT
        fourier = fft(audio);  % Transformada de Fourier
        fourier = fourier(1:floor(N/2));  % Solo la mitad positiva
        frecuencias = (0:floor(N/2)-1)*(Fs/N);  % Vector de frecuencias para la mitad positiva

        % Graficar la FFT sin que cambie el tamaño
        plot(app.axisFFT, frecuencias, abs(fourier / N), "k", 'LineWidth', 1.5);
        title(app.axisFFT, 'Transformada de Fourier del Audio');
        xlabel(app.axisFFT, 'Frecuencia [Hz]');
        ylabel(app.axisFFT, 'Magnitud');

        % Encontrar la frecuencia fundamental
        [magnitud_max, idx] = max(abs(fourier));  % Encuentra el pico máximo
        frecuencia_fundamental = frecuencias(idx);  % Obtiene la frecuencia correspondiente

        % Mostrar la frecuencia fundamental en un campo de texto
        app.frecuenciaFundamental.Value = num2str(frecuencia_fundamental);
    end
end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            cla(app.senal, "reset")
            cla(app.axisFFT, "reset")
        end

        % Button pushed function: Audio1Perros
        function Audio1PerrosButtonPushed(app, event)

           [audio, Fs] = audioread("perros1.wav");
           app.calcularFrecuenciaFundamental(audio, Fs)

        end

        % Button pushed function: Audio2Perros
        function Audio2PerrosButtonPushed(app, event)
           [audio, Fs] = audioread("perros2.wav");
           app.calcularFrecuenciaFundamental(audio, Fs)
        end

        % Button pushed function: Audio3Perros
        function Audio3PerrosButtonPushed(app, event)
           [audio, Fs] = audioread("perros3.wav");
           app.calcularFrecuenciaFundamental(audio, Fs)
        end

        % Button pushed function: Audio1Construccion
        function Audio1ConstruccionButtonPushed(app, event)
           [audio, Fs] = audioread("construccion1.wav");
           app.calcularFrecuenciaFundamental(audio, Fs) 
        end

        % Button pushed function: Audio2Construccion
        function Audio2ConstruccionButtonPushed(app, event)
           [audio, Fs] = audioread("construccion2.wav");
           app.calcularFrecuenciaFundamental(audio, Fs)      
        end

        % Button pushed function: Audio3Construccion
        function Audio3ConstruccionButtonPushed(app, event)
           [audio, Fs] = audioread("construccion3.wav");
           app.calcularFrecuenciaFundamental(audio, Fs)    
        end

        % Button pushed function: Audio1Childrens
        function Audio1ChildrensButtonPushed(app, event)
           [audio, Fs] = audioread("childrens1.wav");
           app.calcularFrecuenciaFundamental(audio, Fs)    
        end

        % Button pushed function: Audio2Childrens
        function Audio2ChildrensButtonPushed(app, event)
           [audio, Fs] = audioread("childrens2.wav");
           app.calcularFrecuenciaFundamental(audio, Fs)    
        end

        % Button pushed function: Audio3Childrens
        function Audio3ChildrensButtonPushed(app, event)
           [audio, Fs] = audioread("childrens3.wav");
           app.calcularFrecuenciaFundamental(audio, Fs)    
        end

        % Button pushed function: Audio1Music
        function Audio1MusicButtonPushed(app, event)
           [audio, Fs] = audioread("music1.wav");
           app.calcularFrecuenciaFundamental(audio, Fs)    
        end

        % Button pushed function: Audio2Music
        function Audio2MusicButtonPushed(app, event)
           [audio, Fs] = audioread("music2.wav");
           app.calcularFrecuenciaFundamental(audio, Fs)    
        end

        % Button pushed function: Audio3Music
        function Audio3MusicButtonPushed(app, event)
           [audio, Fs] = audioread("music3.wav");
           app.calcularFrecuenciaFundamental(audio, Fs)    
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.senal = uiaxes(app.UIFigure);
            title(app.senal, 'Señal de Audio')
            xlabel(app.senal, 'Tiempo [s]')
            ylabel(app.senal, 'Amplitud')
            zlabel(app.senal, 'Z')
            app.axisFFT = uiaxes(app.UIFigure);
            title(app.axisFFT, 'Transformada de Fourier')
            xlabel(app.axisFFT, 'Frecuencia [Hz]')
            ylabel(app.axisFFT, 'Magnitud')
            zlabel(app.axisFFT, 'Z')
            app.ExtraccindecaractersticasLabel = uilabel(app.UIFigure);
            app.ExtraccindecaractersticasLabel.Text = 'Extracción de características';
            app.PerrosLabel = uilabel(app.UIFigure);
            app.PerrosLabel.Text = 'Perros';
            app.Audio1Perros = uibutton(app.UIFigure, 'push');
            app.Audio1Perros.ButtonPushedFcn = createCallbackFcn(app, @Audio1PerrosButtonPushed, true);
            app.Audio1Perros.Text = 'Audio 1';
            app.Audio2Perros = uibutton(app.UIFigure, 'push');
            app.Audio2Perros.ButtonPushedFcn = createCallbackFcn(app, @Audio2PerrosButtonPushed, true);
            app.Audio2Perros.Text = 'Audio 2';
            app.Audio3Perros = uibutton(app.UIFigure, 'push');
            app.Audio3Perros.ButtonPushedFcn = createCallbackFcn(app, @Audio3PerrosButtonPushed, true);
            app.Audio3Perros.Text = 'Audio 3';
            app.ConstruccinLabel = uilabel(app.UIFigure);
            app.ConstruccinLabel.Text = 'Construcción';
            app.Audio1Construccion = uibutton(app.UIFigure, 'push');
            app.Audio1Construccion.ButtonPushedFcn = createCallbackFcn(app, @Audio1ConstruccionButtonPushed, true);
            app.Audio1Construccion.Text = 'Audio 1';
            app.Audio2Construccion = uibutton(app.UIFigure, 'push');
            app.Audio2Construccion.ButtonPushedFcn = createCallbackFcn(app, @Audio2ConstruccionButtonPushed, true);
            app.Audio2Construccion.Text = 'Audio 2';
            app.Audio3Construccion = uibutton(app.UIFigure, 'push');
            app.Audio3Construccion.ButtonPushedFcn = createCallbackFcn(app, @Audio3ConstruccionButtonPushed, true);
            app.Audio3Construccion.Text = 'Audio 3';
            app.frecuenciaFundamental = uieditfield(app.UIFigure, 'text');
            app.frecuenciaFundamental.Editable = 'off';
            app.frecuenciafundamentalLabel = uilabel(app.UIFigure);
            app.frecuenciafundamentalLabel.Text = 'frecuencia fundamental';
            app.NiosjugandoLabel = uilabel(app.UIFigure);
            app.NiosjugandoLabel.Text = 'Niños jugando';
            app.Audio1Childrens = uibutton(app.UIFigure, 'push');
            app.Audio1Childrens.ButtonPushedFcn = createCallbackFcn(app, @Audio1ChildrensButtonPushed, true);
            app.Audio1Childrens.Text = 'Audio 1';
            app.Audio2Childrens = uibutton(app.UIFigure, 'push');
            app.Audio2Childrens.ButtonPushedFcn = createCallbackFcn(app, @Audio2ChildrensButtonPushed, true);
            app.Audio2Childrens.Text = 'Audio 2';
            app.Audio3Childrens = uibutton(app.UIFigure, 'push');
            app.Audio3Childrens.ButtonPushedFcn = createCallbackFcn(app, @Audio3ChildrensButtonPushed, true);
            app.Audio3Childrens.Text = 'Audio 3';
            app.MusicaLabel = uilabel(app.UIFigure);
            app.MusicaLabel.Text = 'Musica';
            app.Audio1Music = uibutton(app.UIFigure, 'push');
            app.Audio1Music.ButtonPushedFcn = createCallbackFcn(app, @Audio1MusicButtonPushed, true);
            app.Audio1Music.Text = 'Audio 1';
            app.Audio2Music = uibutton(app.UIFigure, 'push');
            app.Audio2Music.ButtonPushedFcn = createCallbackFcn(app, @Audio2MusicButtonPushed, true);
            app.Audio2Music.Text = 'Audio 2';
            app.Audio3Music = uibutton(app.UIFigure, 'push');
            app.Audio3Music.ButtonPushedFcn = createCallbackFcn(app, @Audio3MusicButtonPushed, true);
            app.Audio3Music.Text = 'Audio 3';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end