classdef interfaz < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        MuestreoTab                     matlab.ui.container.Tab
        AccionesLabel                   matlab.ui.control.Label
        FuncionesLabel                  matlab.ui.control.Label
        CBsumaSenales                   matlab.ui.control.CheckBox
        CBtriangular                    matlab.ui.control.CheckBox
        CBcuadrada                      matlab.ui.control.CheckBox
        CBruidoSenales                  matlab.ui.control.CheckBox
        CBcoseno                        matlab.ui.control.CheckBox
        CBseno                          matlab.ui.control.CheckBox
        ActualizarDatosButton           matlab.ui.control.Button
        milisegundosLabel               matlab.ui.control.Label
        tiempo                          matlab.ui.control.NumericEditField
        TiempoLabel                     matlab.ui.control.Label
        HzLabel_2                       matlab.ui.control.Label
        fmuestreo                       matlab.ui.control.NumericEditField
        FrecuenciademuestreoLabel       matlab.ui.control.Label
        gradosLabel                     matlab.ui.control.Label
        desfase                         matlab.ui.control.NumericEditField
        DesfaseLabel                    matlab.ui.control.Label
        HzLabel                         matlab.ui.control.Label
        frecuencia                      matlab.ui.control.NumericEditField
        FrecuenciaLabel                 matlab.ui.control.Label
        amplitud                        matlab.ui.control.Spinner
        AmplitudLabel                   matlab.ui.control.Label
        axeFase                         matlab.ui.control.UIAxes
        axeFFT                          matlab.ui.control.UIAxes
        axeFFTcentrada                  matlab.ui.control.UIAxes
        axeNormal                       matlab.ui.control.UIAxes
        ExtraccindecaractersticasTab    matlab.ui.container.Tab
        CBruido                         matlab.ui.control.CheckBox
        frecuenciafundamentalFFTconruidoLabel  matlab.ui.control.Label
        RuidoFF                         matlab.ui.control.EditField
        Audio3Music                     matlab.ui.control.Button
        Audio2Music                     matlab.ui.control.Button
        Audio1Music                     matlab.ui.control.Button
        MusicaLabel                     matlab.ui.control.Label
        Audio3Childrens                 matlab.ui.control.Button
        Audio2Childrens                 matlab.ui.control.Button
        Audio1Childrens                 matlab.ui.control.Button
        NiosjugandoLabel                matlab.ui.control.Label
        frecuenciafundamentalFFTLabel   matlab.ui.control.Label
        frecuenciaFundamental           matlab.ui.control.EditField
        Audio3Construccion              matlab.ui.control.Button
        Audio2Construccion              matlab.ui.control.Button
        Audio1Construccion              matlab.ui.control.Button
        ConstruccinLabel                matlab.ui.control.Label
        Audio3Perros                    matlab.ui.control.Button
        Audio2Perros                    matlab.ui.control.Button
        Audio1Perros                    matlab.ui.control.Button
        PerrosLabel                     matlab.ui.control.Label
        RudioFFT                        matlab.ui.control.UIAxes
        RudioAxis                       matlab.ui.control.UIAxes
        axisFFT                         matlab.ui.control.UIAxes
        senal                           matlab.ui.control.UIAxes
        FiltrosyventaneoTab             matlab.ui.container.Tab
        TxtBeta                         matlab.ui.control.NumericEditField
        BetaEditFieldLabel              matlab.ui.control.Label
        TxtFc                           matlab.ui.control.NumericEditField
        FrecuenciadecorteFcEditFieldLabel  matlab.ui.control.Label
        Audio3Music_2                   matlab.ui.control.Button
        Audio2Music_2                   matlab.ui.control.Button
        Audio1Music_2                   matlab.ui.control.Button
        MusicaLabel_2                   matlab.ui.control.Label
        Audio3Childrens_2               matlab.ui.control.Button
        Audio2Childrens_2               matlab.ui.control.Button
        Audio1Childrens_2               matlab.ui.control.Button
        NiosjugandoLabel_2              matlab.ui.control.Label
        Audio3Construccion_2            matlab.ui.control.Button
        Audio2Construccion_2            matlab.ui.control.Button
        Audio1Construccion_2            matlab.ui.control.Button
        ConstruccinLabel_2              matlab.ui.control.Label
        Audio3Perros_2                  matlab.ui.control.Button
        Audio2Perros_2                  matlab.ui.control.Button
        Audio1Perros_2                  matlab.ui.control.Button
        PerrosLabel_2                   matlab.ui.control.Label
        graficaFaseCero                 matlab.ui.control.UIAxes
        graficaRetardoGrupo             matlab.ui.control.UIAxes
        graficaOriginalFiltrada         matlab.ui.control.UIAxes
        graficaRespuestaFiltro          matlab.ui.control.UIAxes
        graficaFFT                      matlab.ui.control.UIAxes
        graficaTiempo                   matlab.ui.control.UIAxes
        ClasificacinITab                matlab.ui.container.Tab
        PitchyMFCCLabel                 matlab.ui.control.Label
        ConfusionChartPanel             matlab.ui.container.Panel
        EstadodelentrenamientoTextArea  matlab.ui.control.TextArea
        EstadodelentrenamientoTextAreaLabel  matlab.ui.control.Label
        PrecisindelmodeloEditField      matlab.ui.control.EditField
        PrecisindelmodeloEditFieldLabel  matlab.ui.control.Label
        ClasificarButton                matlab.ui.control.Button
        ClasificacinIITab               matlab.ui.container.Tab
        EvaluarButton                   matlab.ui.control.Button
        LogTextArea                     matlab.ui.control.TextArea
        EstadodelentrenamientoTextArea_2Label  matlab.ui.control.Label
        PrecisindelmodeloEditField_2    matlab.ui.control.EditField
        PrecisindelmodeloEditField_2Label  matlab.ui.control.Label
        SeparacindelhablanteLabel       matlab.ui.control.Label
        EntrenarButton                  matlab.ui.control.Button
        ResultsAxes_2                   matlab.ui.control.UIAxes
        ResultsAxes                     matlab.ui.control.UIAxes
    end

    
    methods (Access = public)
        function calcularFrecuenciaFundamental(app, audio, Fs)
    
        % Reproduce el audio
        sound(audio, Fs);
        
        % Parámetros
        N = length(audio);  % Número de muestras
        t = (0:N-1)/Fs;     % Vector de tiempo en segundos
        
        % Graficar la señal en el dominio del tiempo
        plot(app.senal, t, audio, "k", 'LineWidth', 1.5);
        title(app.senal, 'Señal de Audio en el Tiempo');
        xlabel(app.senal, 'Tiempo [s]');
        ylabel(app.senal, 'Amplitud');
        
        % Transformada de Fourier (FFT)
        Y = fft(audio);                     % Transformada de Fourier
        P2 = abs(Y/N);                      % Espectro de amplitud bidireccional
        P1 = P2(1:floor(N/2)+1);            % Espectro de amplitud de un solo lado
        P1(2:end-1) = 2*P1(2:end-1);        % Ajuste de amplitudes para el espectro de un solo lado
        f = Fs*(0:floor(N/2))/N;            % Vector de frecuencias para el espectro de un solo lado
        
        % Graficar el espectro de amplitud de un solo lado
        plot(app.axisFFT, f, P1, "k", 'LineWidth', 1.5);
        title(app.axisFFT, 'Espectro de Amplitud de la Señal de Audio');
        xlabel(app.axisFFT, 'Frecuencia [Hz]');
        ylabel(app.axisFFT, '|P1(f)|');
        
        % Encontrar la frecuencia fundamental
        [magnitud_max, idx] = max(P1);        % Encuentra el pico máximo
        frecuencia_fundamental = f(idx);      % Obtiene la frecuencia correspondiente
        app.frecuenciaFundamental.Value = num2str(frecuencia_fundamental);  % Mostrar en el UI
        
        % Añadir ruido a la señal original
        X = audio + randn(size(audio));     % Señal con ruido agregado
        
        % Graficar señal ruidosa en el dominio del tiempo
        plot(app.RudioAxis, t, X, "k", 'LineWidth', 1.5);
        title(app.RudioAxis, 'Señal de Audio en el Tiempo con Ruido');
        xlabel(app.RudioAxis, 'Tiempo [s]');
        ylabel(app.RudioAxis, 'Amplitud');
        
        % Transformada de Fourier de la señal ruidosa
        Y_noise = fft(X);                     % Transformada de Fourier de la señal ruidosa
        P2_noise = abs(Y_noise/N);            % Espectro de amplitud bidireccional con ruido
        P1_noise = P2_noise(1:floor(N/2)+1);  % Espectro de amplitud de un solo lado
        P1_noise(2:end-1) = 2*P1_noise(2:end-1);  % Ajuste de amplitudes para un solo lado

                % Encontrar la frecuencia fundamental
        [magnitud_max_noise, idx_noise] = max(P1_noise);        % Encuentra el pico máximo
        frecuencia_fundamental_rudio = f(idx_noise);      % Obtiene la frecuencia correspondiente
        app.RuidoFF.Value = num2str(frecuencia_fundamental_rudio);  % Mostrar en el UI
    
        % Graficar el espectro de amplitud de la señal ruidosa
        plot(app.RudioFFT, f, P1_noise, "k", 'LineWidth', 1.5);
        title(app.RudioFFT, 'Espectro de Amplitud de la Señal con Ruido');
        xlabel(app.RudioFFT, 'Frecuencia [Hz]');
        ylabel(app.RudioFFT, '|P1\_noise(f)|');
    end 
       
        function filtroKIRyVentaneo(app, audio, Fs)

            % Reproduce el audio
            sound(audio, Fs);

            N = length(audio);  % Número de muestras
            t = (0:N-1)/Fs;  % Vector de tiempo para graficar la señal en segundos

            % Graficar la señal en el dominio del tiempo
            plot(app.graficaTiempo, t, audio, "k", 'LineWidth', 1.5);
            title(app.graficaTiempo, 'Señal de Audio en el Tiempo');
            xlabel(app.graficaTiempo, 'Tiempo [s]');
            ylabel(app.graficaTiempo, 'Amplitud');
            
          
            % Transformada de Fourier para visualizar el espectro de frecuencias
            Y = fft(audio);
            f = (0:N-1)*(Fs/N);  % Vector de frecuencias
            P2 = abs(Y/N);       % Espectro de amplitud bidireccional
            P1 = P2(1:floor(N/2)+1); % Espectro de amplitud de un solo lado
        
            % Graficar el espectro de amplitud de un solo lado
            plot(app.graficaFFT, f(1:floor(N/2)+1), 20*log10(P1), "b", 'LineWidth', 1.5);
            title(app.graficaFFT, 'Espectro de Amplitud de la Señal de Audio');
            xlabel(app.graficaFFT, 'Frecuencia [Hz]');
            ylabel(app.graficaFFT, 'Magnitud (dB)');            

            % Frecuencia de corte (ajustarla en función del análisis del espectro)
            Wn = (2/Fs)*app.TxtFc.Value;  % Normalización de frecuencia de corte

            % Diseño del filtro FIR con la ventana de Kaiser
            b = fir1(20, Wn, 'low', kaiser(21, app.TxtBeta.Value));
            [h, f] = freqz(b, 1, [], Fs);

            % Respuesta en frecuencia del filtro
            plot(app.graficaRespuestaFiltro, f, mag2db(abs(h)), "r", 'LineWidth', 1.5);
            title(app.graficaRespuestaFiltro, 'Respuesta en Frecuencia del Filtro');
            xlabel(app.graficaRespuestaFiltro, 'Frecuencia [Hz]');
            ylabel(app.graficaRespuestaFiltro, 'Magnitud (dB)');       

            % Filtrar la señal
            y = filter(b, 1, audio);

            % Graficar señal original y filtrada
            plot(app.graficaOriginalFiltrada, t(1:1000), audio(1:1000), t(1:1000), y(1:1000), "r", 'LineWidth', 1.5);
            title(app.graficaOriginalFiltrada, 'Señal original y señal filtrada');
            xlabel(app.graficaOriginalFiltrada, 'Tiempo (s)');
            ylabel(app.graficaOriginalFiltrada, 'Amplitud');   
            legend(app.graficaOriginalFiltrada,'Señal Original', 'Señal Filtrada')

            % Calcular y graficar el retardo de grupo
            [gd, f] = grpdelay(b, 1, [], Fs);

            plot(app.graficaRetardoGrupo, f, gd, "r", 'LineWidth', 1.5);
            title(app.graficaRetardoGrupo, 'Retardo de grupo');
            xlabel(app.graficaRetardoGrupo, 'Frecuencia (Hz)');
            ylabel(app.graficaRetardoGrupo, 'Retardo de Grupo (muestras)');   

            % Filtrado con fase cero
            yzp = filtfilt(b, 1, audio);

            plot(app.graficaFaseCero, t(1:1000), audio(1:1000), t(1:1000), y(1:1000), t(1:1000), yzp(1:1000), "r", 'LineWidth', 1.5);
            title(app.graficaFaseCero, 'Filtro con fase cero');
            xlabel(app.graficaFaseCero, 'Tiempo (s)');
            ylabel(app.graficaFaseCero, 'Amplitud');   
            legend(app.graficaFaseCero,'Señal Original', 'Señal Filtrada', 'Filtrado de Fase Cero', 'Location', 'NorthEast')

        end

        function GenerateConfusionChart(app, labels, predictedLabels)
            % Elimina cualquier gráfico previo en el panel
            delete(allchild(app.ConfusionChartPanel));

            % Crea un nuevo gráfico de confusión dentro del panel
            confusionChartObj = confusionchart(labels, predictedLabels, ...
                'Title', 'Matriz de confusión', ...
                'ColumnSummary', "column-normalized", ...
                'RowSummary', "row-normalized");

            % Ajusta la posición del gráfico para que se adapte al panel
            confusionChartObj.Parent = app.ConfusionChartPanel;
        end

        function stop = updateTrainingProgress(app, info)
            stop = false; % Indica si se debe detener el entrenamiento (false para continuar).

            if info.State == "iteration"
                % Mostrar información en el área de texto
                app.LogTextArea.Value = [app.LogTextArea.Value; ...
                    sprintf("Epoch %d, Iteration %d, Loss: %.4f", ...
                    info.Epoch, info.Iteration, info.TrainingLoss)];
            end
        end

    
    end
    
    methods (Access = private)
        
        function net = convtasnet(app, varargin)
            % This model and its parameters are described in:
            % [1] Luo, Yi, and Nima Mesgarani. "Conv-TasNet: Surpassing Ideal
            % Time–Frequency Magnitude Masking for Speech Separation." IEEE/ACM
            % Transactions on Audio, Speech, and Language Processing, vol. 27, no. 8,
            % Aug. 2019, pp. 1256–66. DOI.org (Crossref),
            % https://doi.org/10.1109/TASLP.2019.2915167.
            % Parámetros por defecto
            % Parámetros por defecto
            defaults = struct( ...
                'NumFiltersAutoEncoder', 512, ...
                'FilterLength', 16, ...
                'NumChannelsBottleneck', 128, ...
                'NumChannelsConvBlocks', 512, ...
                'NumChannelsSkipPaths', 128, ...
                'KernelSizeConvBlocks', 3, ...
                'NumConvBlocksInRepeat', 8, ...
                'NumRepeats', 3, ...
                'NumResponses', 2 ...
                );

            % Combinar argumentos con valores por defecto
            options = app.parseArguments(defaults, varargin{:});

            % Asignar variables desde options
            N = options.NumFiltersAutoEncoder;
            L = options.FilterLength;
            B = options.NumChannelsBottleneck;
            H = options.NumChannelsConvBlocks;
            Sc = options.NumChannelsSkipPaths;
            P = options.KernelSizeConvBlocks;
            X = options.NumConvBlocksInRepeat;
            R = options.NumRepeats;
            numResponses = options.NumResponses;

            % Construir la red neuronal (aquí sigue tu lógica original)
            net = dlnetwork();

            % Encoder + Bottleneck -----------------------------------------------------
            net = addLayers(net,[ ...
                sequenceInputLayer(1,MinLength=L,Name="input")
                convolution1dLayer(L,N,Stride=L/2,Name="encoder.conv1d")
                layerNormalizationLayer(OperationDimension="batch-excluded",Name="bottleneck.layernorm")
                convolution1dLayer(1,B,Name="bottleneck.conv1d")]);


            % TCN ----------------------------------------------------------------------
            previousLayer = "bottleneck.conv1d";
            dilationFactor = repmat((2.^(0:X-1)),1,R);
            net = addLayers(net,additionLayer(X*R,Name="skip_connection"));

            for ii = 1:(X*R)

                net = addLayers(net,[ ...
                    convolution1dLayer(1,H,Name="TCN."+ii+".conv1d")
                    preluLayer(Name="TCN."+ii+"prelu_1")
                    layerNormalizationLayer(OperationDimension="batch-excluded",Name="TCN."+ii+".layernorm_1")

                    groupedConvolution1dLayer(FilterSize=P,DilationFactor=dilationFactor(ii),Name="TCN."+ii+".groupconv1d")
                    preluLayer(Name="TCN."+ii+".prelu_2")
                    layerNormalizationLayer(OperationDimension="batch-excluded",Name="TCN."+ii+".layernorm_2")

                    convolution1dLayer(1,Sc,Name="TCN."+ii+".skip.conv1d")
                    ]);

                if ii~=(X*R)
                    net = addLayers(net,[ ...
                        convolution1dLayer(1,B,Name="TCN."+ii+".residual.conv1d") ...
                        additionLayer(2,Name="TCN."+ii+".output+residual")]);

                    net = connectLayers(net,"TCN."+ii+".layernorm_2","TCN."+ii+".residual.conv1d");
                    net = connectLayers(net,previousLayer,"TCN."+ii+".output+residual"+"/in2");
                end

                net = connectLayers(net,previousLayer,"TCN."+ii+".conv1d");

                previousLayer = "TCN."+ii+".output+residual";

                net = connectLayers(net,"TCN."+(ii)+".skip.conv1d","skip_connection/in"+ii);
            end


            % Output -------------------------------------------------------------------
            net = addLayers(net,[ ...
                preluLayer(Name="mask.prelu")
                convolution1dLayer(1,numResponses*N,Name="mask.conv1d")
                splitMaskLayer(NumSpeakers=numResponses,Name="splitMask")
                reluLayer(Name='relu') ... % The original paper uses sigmoid. Common practice now is ReLU, but results have not been compared.
                ]);
            net = connectLayers(net,"skip_connection","mask.prelu");

            % Apply Mask
            net = addLayers(net,[ ...
                addDimLayer(Name="viewLayer_2",DimToAdd="S")
                multiplicationLayer(2,Name="apply_mask")
                reformatLayer(Name="viewLayer_3",Format="TCBS")
                transposedConv1dLayer(L,1,Stride=L/2,Name='return')
                reformatLayer(Name="viewLayer_4",Format="TCBS")
                removeDimLayer(Name="formatOutput",DimToRemove='C')]);

            net = connectLayers(net,"encoder.conv1d","viewLayer_2");
            net = connectLayers(net,"relu","apply_mask/in2");

            net = initialize(net,dlarray(zeros(L,1,'single'),'TC'));

        end

        function options = parseArguments(app, defaults, varargin)
            % Función para procesar argumentos opcionales
            parser = inputParser();
            fields = fieldnames(defaults);
            for i = 1:numel(fields)
                addParameter(parser, fields{i}, defaults.(fields{i}));
            end
            parse(parser, varargin{:});
            options = parser.Results;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            cla(app.senal, "reset")
            cla(app.axisFFT, "reset")
            cla(app.RudioAxis, "reset")

        end

        % Button pushed function: ActualizarDatosButton
        function ActualizarDatosButtonPushed(app, event)
            global A f des fs tiempo;
            cla(app.axeNormal, "reset")
            cla(app.axeFFTcentrada, "reset") 
            cla(app.axeFFT, "reset") 
            cla(app.axeFase, "reset") 
            
            A = app.amplitud.Value;
            f = app.frecuencia.Value;
            des = app.desfase.Value;
            fs = app.fmuestreo.Value;
            tiempo = app.tiempo.Value;
            
            T = tiempo / 1000;
            Ts = 1 / fs;
            t = 0:Ts:T-Ts;
            N = length(t);
            
            freqs = (0:N-1)*(fs/N);
            freqs_central = (-N/2:N/2-1)*(fs/N);
            
            x_sum = zeros(size(t));
            fft_x_sum = zeros(size(freqs));
            x_sum_freqs_central = zeros(size(freqs_central));
            x_sum_fase = zeros(size(freqs));
            
            % Lista de señales
            signals = {
                'seno', @sin, app.CBseno.Value, 'b';
                'coseno', @cos, app.CBcoseno.Value, 'r';
                'cuadrada', @square, app.CBcuadrada.Value, 'g';
                'triangular', @sawtooth, app.CBtriangular.Value, 'm';
            };
            
            for i = 1:size(signals, 1)
                if signals{i, 3} == 1
                    x = A * signals{i, 2}(2 * pi * f * t + deg2rad(des));
                    
                    % Aplicar ruido si es necesario
                    if app.CBruidoSenales.Value == 1
                        x = x + randn(size(x));
                    end
            
                    % FFT y gráficos
                    [fft_x, fft_centrada_x, fase_x] = calculateFFT(x);
            
                    fft_x_sum = fft_x_sum + fft_x;
                    x_sum_freqs_central = x_sum_freqs_central + fft_centrada_x;
                    x_sum_fase = x_sum_fase + fase_x;
            
                    plotSignals(t, x, freqs, fft_x, freqs_central, fft_centrada_x, fase_x, signals{i, 4}, app);
                    
                    x_sum = x_sum + x;
                end
            end
            
            % Suma de señales
            if app.CBsumaSenales.Value == 1
                if app.CBruidoSenales.Value == 1
                    x_sum = x_sum + randn(size(x_sum));
                end
                plotSignals(t, x_sum, freqs, fft_x_sum, freqs_central, x_sum_freqs_central, x_sum_fase, 'k', app);
            end
            
            % Leyenda dinámica
            createLegend(signals, app);
            
            % Funciones auxiliares
            function [fft_x, fft_centrada_x, fase_x] = calculateFFT(x)
                fft_x = fft(x);
                fft_centrada_x = fftshift(fft_x);
                fase_x = angle(fft_centrada_x);
            end
            
            function plotSignals(t, x, freqs, fft_x, freqs_central, fft_centrada_x, fase_x, color, app)
                plot(app.axeNormal, t, x, color, 'LineWidth', 1.5);
                title(app.axeNormal,'Normal');
                hold(app.axeNormal, 'on');
                plot(app.axeFFT, freqs, abs(fft_x) / length(t), color, 'LineWidth', 1.5);
                title(app.axeFFT,'FFT');
                hold(app.axeFFT, 'on');
                plot(app.axeFFTcentrada, freqs_central, abs(fft_centrada_x) / length(t), color, 'LineWidth', 1.5);
                title(app.axeFFTcentrada,'FFT Centrada');
                hold(app.axeFFTcentrada, 'on');
                plot(app.axeFase, freqs_central, fase_x, color, 'LineWidth', 1.5);
                title(app.axeFase,'Fase');
                hold(app.axeFase, 'on');
            end
            
            function createLegend(signals, app)
                legendList = {};
                for i = 1:size(signals, 1)
                    if signals{i, 3} == 1
                        legendList{end+1} = ['Señal ' signals{i, 1}];
                    end
                end
                if app.CBsumaSenales.Value == 1
                    legendList{end+1} = 'Suma de Señales';
                end
                legend(app.axeNormal, legendList);
                hold(app.axeNormal, 'off');
                hold(app.axeFFTcentrada, 'off');
            end

        end

        % Button pushed function: Audio1Perros
        function Audio1PerrosButtonPushed(app, event)
           [audio, Fs] = audioread("perros1.wav");
           app.calcularFrecuenciaFundamental(audio, Fs)
        end

        % Button pushed function: Audio2Perros
        function Audio2PerrosButtonPushed(app, event)
            [audio, Fs] = audioread("perros2.wav");
           app.calcularFrecuenciaFundamental(audio, Fs)
        end

        % Button pushed function: Audio3Perros
        function Audio3PerrosButtonPushed(app, event)
            [audio, Fs] = audioread("perros3.wav");
           app.calcularFrecuenciaFundamental(audio, Fs)
        end

        % Button pushed function: Audio1Construccion
        function Audio1ConstruccionButtonPushed(app, event)
            [audio, Fs] = audioread("construccion1.wav");
           app.calcularFrecuenciaFundamental(audio, Fs) 
        end

        % Button pushed function: Audio2Construccion
        function Audio2ConstruccionButtonPushed(app, event)
            [audio, Fs] = audioread("construccion2.wav");
           app.calcularFrecuenciaFundamental(audio, Fs) 
        end

        % Button pushed function: Audio3Construccion
        function Audio3ConstruccionButtonPushed(app, event)
            [audio, Fs] = audioread("construccion3.wav");
           app.calcularFrecuenciaFundamental(audio, Fs) 
        end

        % Button pushed function: Audio1Childrens
        function Audio1ChildrensButtonPushed(app, event)
            [audio, Fs] = audioread("childrens1.wav");
           app.calcularFrecuenciaFundamental(audio, Fs)   
        end

        % Button pushed function: Audio2Childrens
        function Audio2ChildrensButtonPushed(app, event)
            [audio, Fs] = audioread("childrens2.wav");
           app.calcularFrecuenciaFundamental(audio, Fs)   
        end

        % Button pushed function: Audio3Childrens
        function Audio3ChildrensButtonPushed(app, event)
            [audio, Fs] = audioread("childrens3.wav");
           app.calcularFrecuenciaFundamental(audio, Fs)   
        end

        % Button pushed function: Audio1Music
        function Audio1MusicButtonPushed(app, event)
            [audio, Fs] = audioread("music1.wav");
           app.calcularFrecuenciaFundamental(audio, Fs)  
        end

        % Button pushed function: Audio2Music
        function Audio2MusicButtonPushed(app, event)
            [audio, Fs] = audioread("music2.wav");
           app.calcularFrecuenciaFundamental(audio, Fs)  
        end

        % Button pushed function: Audio3Music
        function Audio3MusicButtonPushed(app, event)
            [audio, Fs] = audioread("music3.wav");
           app.calcularFrecuenciaFundamental(audio, Fs)  
        end

        % Button pushed function: Audio1Perros_2
        function Audio1Perros_2ButtonPushed(app, event)
            [audio, Fs] = audioread("perros1.wav");
           app.filtroKIRyVentaneo(audio, Fs)
        end

        % Button pushed function: Audio2Perros_2
        function Audio2Perros_2ButtonPushed(app, event)
            [audio, Fs] = audioread("perros2.wav");
           app.filtroKIRyVentaneo(audio, Fs)
        end

        % Button pushed function: Audio3Perros_2
        function Audio3Perros_2ButtonPushed(app, event)
            [audio, Fs] = audioread("perros3.wav");
           app.filtroKIRyVentaneo(audio, Fs)
        end

        % Button pushed function: Audio1Construccion_2
        function Audio1Construccion_2ButtonPushed(app, event)
             [audio, Fs] = audioread("construccion1.wav");
           app.filtroKIRyVentaneo(audio, Fs) 
        end

        % Button pushed function: Audio2Construccion_2
        function Audio2Construccion_2ButtonPushed(app, event)
             [audio, Fs] = audioread("construccion2.wav");
           app.filtroKIRyVentaneo(audio, Fs) 
        end

        % Button pushed function: Audio3Construccion_2
        function Audio3Construccion_2ButtonPushed(app, event)
             [audio, Fs] = audioread("construccion3.wav");
           app.filtroKIRyVentaneo(audio, Fs) 
        end

        % Button pushed function: Audio1Childrens_2
        function Audio1Childrens_2ButtonPushed(app, event)
              [audio, Fs] = audioread("childrens1.wav");
           app.filtroKIRyVentaneo(audio, Fs)  
        end

        % Button pushed function: Audio2Childrens_2
        function Audio2Childrens_2ButtonPushed(app, event)
              [audio, Fs] = audioread("childrens2.wav");
           app.filtroKIRyVentaneo(audio, Fs)  
        end

        % Button pushed function: Audio3Childrens_2
        function Audio3Childrens_2ButtonPushed(app, event)
              [audio, Fs] = audioread("childrens2.wav");
           app.filtroKIRyVentaneo(audio, Fs)  
        end

        % Button pushed function: Audio1Music_2
        function Audio1Music_2ButtonPushed(app, event)
              [audio, Fs] = audioread("music1.wav");
           app.filtroKIRyVentaneo(audio, Fs)  
        end

        % Button pushed function: Audio2Music_2
        function Audio2Music_2ButtonPushed(app, event)
              [audio, Fs] = audioread("music2.wav");
           app.filtroKIRyVentaneo(audio, Fs) 
        end

        % Button pushed function: Audio3Music_2
        function Audio3Music_2ButtonPushed(app, event)
              [audio, Fs] = audioread("music3.wav");
           app.filtroKIRyVentaneo(audio, Fs) 
        end

        % Button pushed function: ClasificarButton
        function ClasificarButtonPushed(app, event)
            inputFolder = 'test/todos';

            ads = audioDatastore(inputFolder, 'IncludeSubfolders', false, 'FileExtensions', '.wav');

            classes = [];
            fileNames = {};

            app.EstadodelentrenamientoTextArea.Value = {'Iniciando entrenamiento...'};

            % Leer los archivos y asignar las clases
            while hasdata(ads)
                [audioData, dsInfo] = read(ads);

                % Extraer clase del nombre del archivo
                [~, fileName, ~] = fileparts(dsInfo.FileName);
                splitName = split(fileName, '_');
                classLabel = str2double(splitName{1});

                % Guardar la clase y el nombre
                classes = [classes; classLabel];
                fileNames{end+1} = dsInfo.FileName;

                app.EstadodelentrenamientoTextArea.Value{end+1} = sprintf('Archivo: %s, Clase: %d, Duración: %.2f segundos', ...
                    fileName, classLabel, numel(audioData) / dsInfo.SampleRate)
                drawnow;
            end
            
            app.EstadodelentrenamientoTextArea.Value{end+1} = sprintf('Se procesaron %d archivos.', numel(fileNames))

            fs = dsInfo.SampleRate;
            windowLength = round(0.03 * fs); % Ventana de 30 ms
            overlapLength = round(0.025 * fs); % Solapamiento de 25 ms

            afe = audioFeatureExtractor( ...
                SampleRate=fs, ...
                Window=hamming(windowLength,"periodic"), ...
                OverlapLength=overlapLength, ...
                zerocrossrate=true, ...
                shortTimeEnergy=true, ...
                pitch=true, ...
                mfcc=true);

            featureMap = info(afe);

            features = [];
            labels = [];
            energyThreshold = 0.005; % Umbral para energía
            zcrThreshold = 0.2; % Umbral para tasa de cruces por cero

            % Reiniciar el puntero del datastore
            reset(ads);

            % Extraer características y filtrar
            while hasdata(ads)
                [audioData, dsInfo] = read(ads);

                % Extraer características del audio
                thisFeature = extract(afe, audioData);
                isSpeech = thisFeature(:, featureMap.shortTimeEnergy) > energyThreshold;
                isVoiced = thisFeature(:, featureMap.zerocrossrate) < zcrThreshold;

                % Identificar regiones de voz
                voicedSpeech = isSpeech & isVoiced;

                % Filtrar características no deseadas
                thisFeature(~voicedSpeech, :) = [];
                thisFeature(:, [featureMap.zerocrossrate, featureMap.shortTimeEnergy]) = [];

                % Obtener la etiqueta correspondiente al archivo
                [~, fileName, ~] = fileparts(dsInfo.FileName);
                splitName = split(fileName, '_');
                classLabel = str2double(splitName{1});

                % Asignar etiquetas
                label = repelem(classLabel, size(thisFeature, 1))';

                % Guardar características y etiquetas
                features = [features; thisFeature];
                labels = [labels; label];
            end

            M = mean(features,1);
            S = std(features,[],1);
            features = (features-M)./S;

            app.EstadodelentrenamientoTextArea.Value{end+1} = 'Extracción terminada'

            load('knnModel.mat', 'trainedClassifier');

            predictedLabels = predict(trainedClassifier, features);

            % Evaluar la precisión comparando las etiquetas predichas con las etiquetas reales
            accuracy = sum(predictedLabels == labels) / length(labels);

            app.PrecisindelmodeloEditField.Value = sprintf("%.2f%%", accuracy * 100)

            GenerateConfusionChart(app, labels, predictedLabels)

        end

        % Button pushed function: EntrenarButton
        function EntrenarButtonPushed(app, event)
            %% Crear ValidationData
            % Crear datastore para señales mezcladas
            ruta_validation_mixed = 'C:\Users\chj06\OneDrive\Documents\Escuela\7_semestre\Reconocimiento_Voz\ReconocimietoVoz\Practicas_Juntas\audios\validation_mixed';
            mixedDatastore = audioDatastore(ruta_validation_mixed, 'IncludeSubfolders', true, OutputDataType="double");

            % Crear datastore para señales objetivo
            ruta_validation_preprocessed = 'C:\Users\chj06\OneDrive\Documents\Escuela\7_semestre\Reconocimiento_Voz\ReconocimietoVoz\Practicas_Juntas\audios\validation_preprocessed';
            targetDatastore = audioDatastore(ruta_validation_preprocessed, 'IncludeSubfolders', true, OutputDataType="double");

            % Combinar datastores
            validationData = combine(mixedDatastore, targetDatastore);

            %% Configurar el modelo
            
            ruta_train = 'C:\Users\chj06\OneDrive\Documents\Escuela\7_semestre\Reconocimiento_Voz\ReconocimietoVoz\Practicas_Juntas\audios\train_preprocessed';
            trainDatastore = audioDatastore(ruta_train, 'IncludeSubfolders', true);


            % Inicializar barra de progreso y área de texto
   
    app.LogTextArea.Value{end+1} = 'Iniciando entrenamiento...';

    % Entrenar la red
    try

        % Configurar el entrenamiento
       

        options = trainingOptions('adam', ...
    'MaxEpochs', 10, ...
    'MiniBatchSize', 6, ...
    'ExecutionEnvironment', 'gpu', ...
    'ValidationData', validationData, ...
    'ValidationFrequency', 10, ...
    'OutputFcn', @(info) updateTrainingProgress(app, info));


       net = app.convtasnet( ...
            'NumFiltersAutoEncoder', 512, ...
            'FilterLength', 16, ...
            'NumChannelsBottleneck', 128, ...
            'NumChannelsConvBlocks', 512, ...
            'NumChannelsSkipPaths', 128, ...
            'KernelSizeConvBlocks', 3, ...
            'NumConvBlocksInRepeat', 8, ...
            'NumRepeats', 3, ...
            'NumResponses', 4);

        app.Model = net;
        app.LogTextArea.Value{end+1} = 'Modelo cargado y listo para entrenar.';

        [app.Net, traininfo] = trainnet(trainDatastore, net, ...
            @(Y, T)-permutationInvariantSISNR(Y, T), options);

        % Mostrar métricas finales en el área de texto
        app.LogTextArea.Value{end+1} = 'Entrenamiento completado.';

        % Graficar métricas de entrenamiento

        plot(app.ResultsAxes, traininfo.TrainingLoss, '-o');
        hold(app.ResultsAxes, 'on');
        plot(app.ResultsAxes, traininfo.ValidationLoss, '-o');
        hold(app.ResultsAxes, 'off');
        legend(app.ResultsAxes, {'Training Loss', 'Validation Loss'});
        title(app.ResultsAxes, 'Pérdidas durante el entrenamiento');
        xlabel(app.ResultsAxes, 'Iteración');
        ylabel(app.ResultsAxes, 'Pérdida');
    catch ME
        app.LogTextArea.Value{end+1} = sprintf('Error en el entrenamiento: %s.', ME.message);
    end


        end

        % Button pushed function: EvaluarButton
        function EvaluarButtonPushed(app, event)
            ruta_eval = 'C:\Users\chj06\OneDrive\Documents\Escuela\7_semestre\Reconocimiento_Voz\ReconocimietoVoz\Practicas_Juntas\audios\validation_preprocessed\2_Diego_Test.wav';
            [audio, fs] = audioread(ruta_eval);
            separatedAudio = predict(app.Net, audio);

            % Reproducir señales separadas
            sound(separatedAudio(:, 1), fs);
            sound(separatedAudio(:, 2), fs);

            % Mostrar resultados (ejemplo: graficar la señal original y separada)
            plot(app.ResultsAxes_2, audio);
            hold(app.ResultsAxes_2, 'on');
            plot(app.ResultsAxes_2, separatedAudio(:, 1));
            plot(app.ResultsAxes_2, separatedAudio(:, 2));
            hold(app.ResultsAxes_2, 'off');
            legend(app.ResultsAxes_2, {'Original', 'Separado 1', 'Separado 2'});
            title(app.ResultsAxes_2, 'Señales separadas');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MuestreoTab = uitab(app.TabGroup);
            app.MuestreoTab.Title = 'Muestreo';
            app.axeNormal = uiaxes(app.MuestreoTab);
            title(app.axeNormal, 'Normal')
            xlabel(app.axeNormal, 'X')
            ylabel(app.axeNormal, 'Y')
            zlabel(app.axeNormal, 'Z')
            app.axeFFTcentrada = uiaxes(app.MuestreoTab);
            title(app.axeFFTcentrada, 'FFT Centrada')
            xlabel(app.axeFFTcentrada, 'X')
            ylabel(app.axeFFTcentrada, 'Y')
            zlabel(app.axeFFTcentrada, 'Z')
            app.axeFFT = uiaxes(app.MuestreoTab);
            title(app.axeFFT, 'FFT')
            xlabel(app.axeFFT, 'X')
            ylabel(app.axeFFT, 'Y')
            zlabel(app.axeFFT, 'Z')
            app.axeFase = uiaxes(app.MuestreoTab);
            title(app.axeFase, 'Fase')
            xlabel(app.axeFase, 'X')
            ylabel(app.axeFase, 'Y')
            zlabel(app.axeFase, 'Z')
            app.AmplitudLabel = uilabel(app.MuestreoTab);
            app.AmplitudLabel.Text = 'Amplitud';
            app.amplitud = uispinner(app.MuestreoTab);
            app.amplitud.Step = 0.5;
            app.amplitud.Limits = [-10 10];
            app.amplitud.Value = 2;
            app.FrecuenciaLabel = uilabel(app.MuestreoTab);
            app.FrecuenciaLabel.Text = 'Frecuencia';
            app.frecuencia = uieditfield(app.MuestreoTab, 'numeric');
            app.frecuencia.Value = 125;
            app.HzLabel = uilabel(app.MuestreoTab);
            app.HzLabel.Text = 'Hz';
            app.DesfaseLabel = uilabel(app.MuestreoTab);
            app.DesfaseLabel.Text = 'Desfase';
            app.desfase = uieditfield(app.MuestreoTab, 'numeric');
            app.desfase.Limits = [0 360];
            app.desfase.Value = 45;
            app.gradosLabel = uilabel(app.MuestreoTab);
            app.gradosLabel.Text = 'grados';
            app.FrecuenciademuestreoLabel = uilabel(app.MuestreoTab);
            app.FrecuenciademuestreoLabel.Text = {'Frecuencia '; 'de muestreo'};
            app.fmuestreo = uieditfield(app.MuestreoTab, 'numeric');
            app.fmuestreo.Value = 1000;
            app.HzLabel_2 = uilabel(app.MuestreoTab);
            app.HzLabel_2.Text = 'Hz';
            app.TiempoLabel = uilabel(app.MuestreoTab);
            app.TiempoLabel.Text = 'Tiempo';
            app.tiempo = uieditfield(app.MuestreoTab, 'numeric');
            app.tiempo.Limits = [0 Inf];
            app.tiempo.Value = 10;
            app.milisegundosLabel = uilabel(app.MuestreoTab);
            app.milisegundosLabel.Text = 'milisegundos';
            app.ActualizarDatosButton = uibutton(app.MuestreoTab, 'push');
            app.ActualizarDatosButton.ButtonPushedFcn = createCallbackFcn(app, @ActualizarDatosButtonPushed, true);
            app.ActualizarDatosButton.Text = 'Actualizar Datos';
            app.CBseno = uicheckbox(app.MuestreoTab);
            app.CBseno.Text = 'Seno';
            app.CBseno.Value = true;
            app.CBcoseno = uicheckbox(app.MuestreoTab);
            app.CBcoseno.Text = 'Coseno';
            app.CBruidoSenales = uicheckbox(app.MuestreoTab);
            app.CBruidoSenales.Text = 'Rudio';
            app.CBcuadrada = uicheckbox(app.MuestreoTab);
            app.CBcuadrada.Text = 'Cuadrada';
            app.CBtriangular = uicheckbox(app.MuestreoTab);
            app.CBtriangular.Text = 'Triangular';
            app.CBsumaSenales = uicheckbox(app.MuestreoTab);
            app.CBsumaSenales.Text = 'Suma de señales';
            app.FuncionesLabel = uilabel(app.MuestreoTab);
            app.FuncionesLabel.Text = 'Funciones';
            app.AccionesLabel = uilabel(app.MuestreoTab);
            app.AccionesLabel.Text = 'Acciones';
            app.ExtraccindecaractersticasTab = uitab(app.TabGroup);
            app.ExtraccindecaractersticasTab.Title = 'Extracción de características';
            app.senal = uiaxes(app.ExtraccindecaractersticasTab);
            title(app.senal, 'Señal de Audio')
            xlabel(app.senal, 'Tiempo [s]')
            ylabel(app.senal, 'Amplitud')
            zlabel(app.senal, 'Z')
            app.axisFFT = uiaxes(app.ExtraccindecaractersticasTab);
            title(app.axisFFT, 'Transformada de Fourier')
            xlabel(app.axisFFT, 'Frecuencia [Hz]')
            ylabel(app.axisFFT, 'Magnitud')
            zlabel(app.axisFFT, 'Z')
            app.RudioAxis = uiaxes(app.ExtraccindecaractersticasTab);
            title(app.RudioAxis, 'Señal de Audio')
            xlabel(app.RudioAxis, 'Tiempo [s]')
            ylabel(app.RudioAxis, 'Amplitud')
            zlabel(app.RudioAxis, 'Z')
            app.RudioFFT = uiaxes(app.ExtraccindecaractersticasTab);
            title(app.RudioFFT, 'Transformada de Fourier')
            xlabel(app.RudioFFT, 'Frecuencia [Hz]')
            ylabel(app.RudioFFT, 'Magnitud')
            zlabel(app.RudioFFT, 'Z')
            app.PerrosLabel = uilabel(app.ExtraccindecaractersticasTab);
            app.PerrosLabel.Text = 'Perros';
            app.Audio1Perros = uibutton(app.ExtraccindecaractersticasTab, 'push');
            app.Audio1Perros.ButtonPushedFcn = createCallbackFcn(app, @Audio1PerrosButtonPushed, true);
            app.Audio1Perros.Text = 'Audio 1';
            app.Audio2Perros = uibutton(app.ExtraccindecaractersticasTab, 'push');
            app.Audio2Perros.ButtonPushedFcn = createCallbackFcn(app, @Audio2PerrosButtonPushed, true);
            app.Audio2Perros.Text = 'Audio 2';
            app.Audio3Perros = uibutton(app.ExtraccindecaractersticasTab, 'push');
            app.Audio3Perros.ButtonPushedFcn = createCallbackFcn(app, @Audio3PerrosButtonPushed, true);
            app.Audio3Perros.Text = 'Audio 3';
            app.ConstruccinLabel = uilabel(app.ExtraccindecaractersticasTab);
            app.ConstruccinLabel.Text = 'Construcción';
            app.Audio1Construccion = uibutton(app.ExtraccindecaractersticasTab, 'push');
            app.Audio1Construccion.ButtonPushedFcn = createCallbackFcn(app, @Audio1ConstruccionButtonPushed, true);
            app.Audio1Construccion.Text = 'Audio 1';
            app.Audio2Construccion = uibutton(app.ExtraccindecaractersticasTab, 'push');
            app.Audio2Construccion.ButtonPushedFcn = createCallbackFcn(app, @Audio2ConstruccionButtonPushed, true);
            app.Audio2Construccion.Text = 'Audio 2';
            app.Audio3Construccion = uibutton(app.ExtraccindecaractersticasTab, 'push');
            app.Audio3Construccion.ButtonPushedFcn = createCallbackFcn(app, @Audio3ConstruccionButtonPushed, true);
            app.Audio3Construccion.Text = 'Audio 3';
            app.frecuenciaFundamental = uieditfield(app.ExtraccindecaractersticasTab, 'text');
            app.frecuenciaFundamental.Editable = 'off';
            app.frecuenciafundamentalFFTLabel = uilabel(app.ExtraccindecaractersticasTab);
            app.frecuenciafundamentalFFTLabel.Text = 'frecuencia fundamental FFT';
            app.NiosjugandoLabel = uilabel(app.ExtraccindecaractersticasTab);
            app.NiosjugandoLabel.Text = 'Niños jugando';
            app.Audio1Childrens = uibutton(app.ExtraccindecaractersticasTab, 'push');
            app.Audio1Childrens.ButtonPushedFcn = createCallbackFcn(app, @Audio1ChildrensButtonPushed, true);
            app.Audio1Childrens.Text = 'Audio 1';
            app.Audio2Childrens = uibutton(app.ExtraccindecaractersticasTab, 'push');
            app.Audio2Childrens.ButtonPushedFcn = createCallbackFcn(app, @Audio2ChildrensButtonPushed, true);
            app.Audio2Childrens.Text = 'Audio 2';
            app.Audio3Childrens = uibutton(app.ExtraccindecaractersticasTab, 'push');
            app.Audio3Childrens.ButtonPushedFcn = createCallbackFcn(app, @Audio3ChildrensButtonPushed, true);
            app.Audio3Childrens.Text = 'Audio 3';
            app.MusicaLabel = uilabel(app.ExtraccindecaractersticasTab);
            app.MusicaLabel.Text = 'Musica';
            app.Audio1Music = uibutton(app.ExtraccindecaractersticasTab, 'push');
            app.Audio1Music.ButtonPushedFcn = createCallbackFcn(app, @Audio1MusicButtonPushed, true);
            app.Audio1Music.Text = 'Audio 1';
            app.Audio2Music = uibutton(app.ExtraccindecaractersticasTab, 'push');
            app.Audio2Music.ButtonPushedFcn = createCallbackFcn(app, @Audio2MusicButtonPushed, true);
            app.Audio2Music.Text = 'Audio 2';
            app.Audio3Music = uibutton(app.ExtraccindecaractersticasTab, 'push');
            app.Audio3Music.ButtonPushedFcn = createCallbackFcn(app, @Audio3MusicButtonPushed, true);
            app.Audio3Music.Text = 'Audio 3';
            app.RuidoFF = uieditfield(app.ExtraccindecaractersticasTab, 'text');
            app.RuidoFF.Editable = 'off';
            app.frecuenciafundamentalFFTconruidoLabel = uilabel(app.ExtraccindecaractersticasTab);
            app.frecuenciafundamentalFFTconruidoLabel.Text = 'frecuencia fundamental FFT con ruido';
            app.CBruido = uicheckbox(app.ExtraccindecaractersticasTab);
            app.CBruido.Text = 'Rudio';
            app.FiltrosyventaneoTab = uitab(app.TabGroup);
            app.FiltrosyventaneoTab.Title = 'Filtros y ventaneo';
            app.graficaTiempo = uiaxes(app.FiltrosyventaneoTab);
            title(app.graficaTiempo, 'Señal de Audio')
            xlabel(app.graficaTiempo, 'Tiempo [s]')
            ylabel(app.graficaTiempo, 'Amplitud')
            zlabel(app.graficaTiempo, 'Z')
            app.graficaFFT = uiaxes(app.FiltrosyventaneoTab);
            title(app.graficaFFT, 'Espectro de amplitud (FFT)')
            xlabel(app.graficaFFT, 'Frecuencia (Hz)')
            ylabel(app.graficaFFT, 'Magnitud (dB)')
            zlabel(app.graficaFFT, 'Z')
            app.graficaRespuestaFiltro = uiaxes(app.FiltrosyventaneoTab);
            title(app.graficaRespuestaFiltro, 'Respuesta en Frecuencia del filtro')
            xlabel(app.graficaRespuestaFiltro, 'Frecuencia (Hz)')
            ylabel(app.graficaRespuestaFiltro, 'Magnitud (dB)')
            zlabel(app.graficaRespuestaFiltro, 'Z')
            app.graficaOriginalFiltrada = uiaxes(app.FiltrosyventaneoTab);
            title(app.graficaOriginalFiltrada, 'Señal original y filtrada')
            xlabel(app.graficaOriginalFiltrada, 'Tiempo [s]')
            ylabel(app.graficaOriginalFiltrada, 'Amplitud')
            zlabel(app.graficaOriginalFiltrada, 'Z')
            app.graficaRetardoGrupo = uiaxes(app.FiltrosyventaneoTab);
            title(app.graficaRetardoGrupo, 'Retardo de grupo')
            xlabel(app.graficaRetardoGrupo, 'Frecuencia (Hz)')
            ylabel(app.graficaRetardoGrupo, 'Retardo de grupo (muestras)')
            zlabel(app.graficaRetardoGrupo, 'Z')
            app.graficaFaseCero = uiaxes(app.FiltrosyventaneoTab);
            title(app.graficaFaseCero, 'Filtrado con fase cero')
            xlabel(app.graficaFaseCero, 'Tiempo [s]')
            ylabel(app.graficaFaseCero, 'Amplitud')
            zlabel(app.graficaFaseCero, 'Z')
            app.PerrosLabel_2 = uilabel(app.FiltrosyventaneoTab);
            app.PerrosLabel_2.Text = 'Perros';
            app.Audio1Perros_2 = uibutton(app.FiltrosyventaneoTab, 'push');
            app.Audio1Perros_2.ButtonPushedFcn = createCallbackFcn(app, @Audio1Perros_2ButtonPushed, true);
            app.Audio1Perros_2.Text = 'Audio 1';
            app.Audio2Perros_2 = uibutton(app.FiltrosyventaneoTab, 'push');
            app.Audio2Perros_2.ButtonPushedFcn = createCallbackFcn(app, @Audio2Perros_2ButtonPushed, true);
            app.Audio2Perros_2.Text = 'Audio 2';
            app.Audio3Perros_2 = uibutton(app.FiltrosyventaneoTab, 'push');
            app.Audio3Perros_2.ButtonPushedFcn = createCallbackFcn(app, @Audio3Perros_2ButtonPushed, true);
            app.Audio3Perros_2.Text = 'Audio 3';
            app.ConstruccinLabel_2 = uilabel(app.FiltrosyventaneoTab);
            app.ConstruccinLabel_2.Text = 'Construcción';
            app.Audio1Construccion_2 = uibutton(app.FiltrosyventaneoTab, 'push');
            app.Audio1Construccion_2.ButtonPushedFcn = createCallbackFcn(app, @Audio1Construccion_2ButtonPushed, true);
            app.Audio1Construccion_2.Text = 'Audio 1';
            app.Audio2Construccion_2 = uibutton(app.FiltrosyventaneoTab, 'push');
            app.Audio2Construccion_2.ButtonPushedFcn = createCallbackFcn(app, @Audio2Construccion_2ButtonPushed, true);
            app.Audio2Construccion_2.Text = 'Audio 2';
            app.Audio3Construccion_2 = uibutton(app.FiltrosyventaneoTab, 'push');
            app.Audio3Construccion_2.ButtonPushedFcn = createCallbackFcn(app, @Audio3Construccion_2ButtonPushed, true);
            app.Audio3Construccion_2.Text = 'Audio 3';
            app.NiosjugandoLabel_2 = uilabel(app.FiltrosyventaneoTab);
            app.NiosjugandoLabel_2.Text = 'Niños jugando';
            app.Audio1Childrens_2 = uibutton(app.FiltrosyventaneoTab, 'push');
            app.Audio1Childrens_2.ButtonPushedFcn = createCallbackFcn(app, @Audio1Childrens_2ButtonPushed, true);
            app.Audio1Childrens_2.Text = 'Audio 1';
            app.Audio2Childrens_2 = uibutton(app.FiltrosyventaneoTab, 'push');
            app.Audio2Childrens_2.ButtonPushedFcn = createCallbackFcn(app, @Audio2Childrens_2ButtonPushed, true);
            app.Audio2Childrens_2.Text = 'Audio 2';
            app.Audio3Childrens_2 = uibutton(app.FiltrosyventaneoTab, 'push');
            app.Audio3Childrens_2.ButtonPushedFcn = createCallbackFcn(app, @Audio3Childrens_2ButtonPushed, true);
            app.Audio3Childrens_2.Text = 'Audio 3';
            app.MusicaLabel_2 = uilabel(app.FiltrosyventaneoTab);
            app.MusicaLabel_2.Text = 'Musica';
            app.Audio1Music_2 = uibutton(app.FiltrosyventaneoTab, 'push');
            app.Audio1Music_2.ButtonPushedFcn = createCallbackFcn(app, @Audio1Music_2ButtonPushed, true);
            app.Audio1Music_2.Text = 'Audio 1';
            app.Audio2Music_2 = uibutton(app.FiltrosyventaneoTab, 'push');
            app.Audio2Music_2.ButtonPushedFcn = createCallbackFcn(app, @Audio2Music_2ButtonPushed, true);
            app.Audio2Music_2.Text = 'Audio 2';
            app.Audio3Music_2 = uibutton(app.FiltrosyventaneoTab, 'push');
            app.Audio3Music_2.ButtonPushedFcn = createCallbackFcn(app, @Audio3Music_2ButtonPushed, true);
            app.Audio3Music_2.Text = 'Audio 3';
            app.FrecuenciadecorteFcEditFieldLabel = uilabel(app.FiltrosyventaneoTab);
            app.FrecuenciadecorteFcEditFieldLabel.Text = {'Frecuencia de'; 'corte (Fc)'};
            app.TxtFc = uieditfield(app.FiltrosyventaneoTab, 'numeric');
            app.TxtFc.Value = 125;
            app.BetaEditFieldLabel = uilabel(app.FiltrosyventaneoTab);
            app.BetaEditFieldLabel.Text = 'Beta';
            app.TxtBeta = uieditfield(app.FiltrosyventaneoTab, 'numeric');
            app.TxtBeta.Value = 3;
            app.ClasificacinITab = uitab(app.TabGroup);
            app.ClasificacinITab.Title = 'Clasificación I';
            app.ClasificarButton = uibutton(app.ClasificacinITab, 'push');
            app.ClasificarButton.ButtonPushedFcn = createCallbackFcn(app, @ClasificarButtonPushed, true);
            app.ClasificarButton.Text = 'Clasificar';
            app.PrecisindelmodeloEditFieldLabel = uilabel(app.ClasificacinITab);
            app.PrecisindelmodeloEditFieldLabel.Text = 'Precisión del modelo';
            app.PrecisindelmodeloEditField = uieditfield(app.ClasificacinITab, 'text');
            app.EstadodelentrenamientoTextAreaLabel = uilabel(app.ClasificacinITab);
            app.EstadodelentrenamientoTextAreaLabel.Text = 'Estado del entrenamiento';
            app.EstadodelentrenamientoTextArea = uitextarea(app.ClasificacinITab);
            app.EstadodelentrenamientoTextArea.Editable = 'off';
            app.ConfusionChartPanel = uipanel(app.ClasificacinITab);
            app.ConfusionChartPanel.Title = 'Matris de confusión';
            app.PitchyMFCCLabel = uilabel(app.ClasificacinITab);
            app.PitchyMFCCLabel.Text = 'Pitch y MFCC';
            app.ClasificacinIITab = uitab(app.TabGroup);
            app.ClasificacinIITab.Title = 'Clasificación II';
            app.ResultsAxes = uiaxes(app.ClasificacinIITab);
            title(app.ResultsAxes, 'Entrenamiento')
            xlabel(app.ResultsAxes, 'X')
            ylabel(app.ResultsAxes, 'Y')
            zlabel(app.ResultsAxes, 'Z')
            app.ResultsAxes_2 = uiaxes(app.ClasificacinIITab);
            title(app.ResultsAxes_2, 'Evaluación')
            xlabel(app.ResultsAxes_2, 'X')
            ylabel(app.ResultsAxes_2, 'Y')
            zlabel(app.ResultsAxes_2, 'Z')
            app.EntrenarButton = uibutton(app.ClasificacinIITab, 'push');
            app.EntrenarButton.ButtonPushedFcn = createCallbackFcn(app, @EntrenarButtonPushed, true);
            app.EntrenarButton.Text = 'Entrenar';
            app.SeparacindelhablanteLabel = uilabel(app.ClasificacinIITab);
            app.SeparacindelhablanteLabel.Text = {'Separación del'; 'hablante'};
            app.PrecisindelmodeloEditField_2Label = uilabel(app.ClasificacinIITab);
            app.PrecisindelmodeloEditField_2Label.Text = 'Precisión del modelo';
            app.PrecisindelmodeloEditField_2 = uieditfield(app.ClasificacinIITab, 'text');
            app.EstadodelentrenamientoTextArea_2Label = uilabel(app.ClasificacinIITab);
            app.EstadodelentrenamientoTextArea_2Label.Text = 'Estado del entrenamiento';
            app.LogTextArea = uitextarea(app.ClasificacinIITab);
            app.LogTextArea.Editable = 'off';
            app.EvaluarButton = uibutton(app.ClasificacinIITab, 'push');
            app.EvaluarButton.ButtonPushedFcn = createCallbackFcn(app, @EvaluarButtonPushed, true);
            app.EvaluarButton.Text = 'Evaluar';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = interfaz

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end